classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Image                        matlab.ui.control.Image
        TabGroup                     matlab.ui.container.TabGroup
        PrepocessingTab              matlab.ui.container.Tab
        ZipimageButton               matlab.ui.control.Button
        ROIforforegroundButton       matlab.ui.control.Button
        TwovanishpointsButton        matlab.ui.control.Button
        ForegroundButton             matlab.ui.control.Button
        ROIfortwovanishpointsButton  matlab.ui.control.Button
        OriginalperspectiveTab       matlab.ui.container.Tab
        VanishpointButton            matlab.ui.control.Button
        BackgroundButton             matlab.ui.control.Button
        NewperspectiveTab            matlab.ui.container.Tab
        DepthEditField               matlab.ui.control.NumericEditField
        DepthEditFieldLabel          matlab.ui.control.Label
        T_yEditField                 matlab.ui.control.NumericEditField
        T_yEditFieldLabel            matlab.ui.control.Label
        T_zEditField                 matlab.ui.control.NumericEditField
        T_zEditFieldLabel            matlab.ui.control.Label
        R_zEditField                 matlab.ui.control.NumericEditField
        R_zEditFieldLabel            matlab.ui.control.Label
        R_yEditField                 matlab.ui.control.NumericEditField
        R_yEditFieldLabel            matlab.ui.control.Label
        T_xEditField                 matlab.ui.control.NumericEditField
        T_xEditFieldLabel            matlab.ui.control.Label
        R_xEditField                 matlab.ui.control.NumericEditField
        R_xEditFieldLabel            matlab.ui.control.Label
        DProjectionButton            matlab.ui.control.Button
        DPlotButton                  matlab.ui.control.Button
        RadiationButton              matlab.ui.control.Button
        OthersTab                    matlab.ui.container.Tab
        SaveButton                   matlab.ui.control.Button
        ReloadButton                 matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        TextArea_2                   matlab.ui.control.TextArea
        TextArea                     matlab.ui.control.TextArea
        SelectanimageButton          matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        vorwand; % True/False
        filebild; % Original geladenes Bild
        filename; % Filename (.png) vom originalen geladenen Bild
        fullname;
        roi_fp2; % Für zweiten Fluchtpunkt
        roi_fp2_new; % Für zweiten Fluchtpunkt
        ROI_p; % Position of roi_fp2_new
        ROI_P; % Position of roi_fp2
        roi_rw; % ROI für Rückwand
        roi_fp; % ROI für Fluchtpunkt
        roi_fg; % ROI für Foreground
        im_width; % Höhe des Bildes
        im_length; % Länge des Bildes
        p; % feature points
        vx_i; % coordinates of vanish point
        vy_i; % coordinates of vanish point
        h; % height and width of middle face (rear wand)
        w; % height and width of middle face (rear wand)
        depth_to_rear; % the definition of a variable for calculating the lenth of each face
        PW; % 3D model (the world coordinates based on P1)
        PW_cam; % reset the x/y/z-dierection of axis in camera1
        maskRGB;
        hm;
        wm;
        pm;
        rxm;
        rym;
        filebild2; % Erzeugt Bild
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.vorwand = false;
        end

        % Button pushed function: SelectanimageButton
        function SelectImageFileButtonPushed(app, event)
            [app.filename, filepath] = uigetfile('*.png; *.jpeg; *.jpg');
            app.fullname = [filepath, app.filename];
            app.filebild = imread(app.fullname);
            [app.im_width, app.im_length, ~] = size(app.filebild);
            imshow(app.filebild, 'Parent', app.UIAxes);
        end

        % Button pushed function: ROIfortwovanishpointsButton
        function ROIfortwovanishpointsButtonPushed(app, event)
            H = findobj(app.UIAxes, 'Type', 'images.roi.Polygon');
            if ~isempty(H)
                delete(H);
            end
            
            app.filebild = app.filebild(1:3:end,1:3:end,:); % Compress the image, or leave it uncompressed if it works
            [app.im_width, app.im_length, ~] = size(app.filebild);
            imshow(app.filebild, 'Parent', app.UIAxes);

            app.roi_fp2 = drawpolygon(app.UIAxes, 'Label', 'Two vanish points', 'Color', 'w');
            app.roi_fp2.Position(2,2) = app.roi_fp2.Position(1,2);
            app.roi_fp2.Position(4,2) = app.roi_fp2.Position(3,2);

            roi_P = app.roi_fp2.Position;
            p1 = [roi_P(1, 1), roi_P(1, 2)];
            p2 = [roi_P(2, 1), roi_P(2, 2)];
            p3 = [roi_P(4, 1), roi_P(4, 2)];
            p4 = [roi_P(3, 1), roi_P(3, 2)];
            app.ROI_p = [p1; p2; p3; p4]';
            
            P1 = [roi_P(4, 1), roi_P(1, 2)];
            P2 = [roi_P(3, 1), roi_P(1, 2)];
            P3 = p3;
            P4 = p4;
            app.ROI_P = [P1; P2; P3; P4]';

            hold(app.UIAxes, "on");
            app.roi_fp2_new = drawpolygon(app.UIAxes, 'Position', [P1(1) P1(2); P2(1) P2(2); P4(1) P4(2); P3(1) P3(2)], 'Color', [0.7 0.7 0.7], 'LineWidth', 1, 'Label', 'Neue Recteck');
            hold(app.UIAxes, "off");

            app.roi_fp2.InteractionsAllowed = 'none';
            app.roi_fp2_new.InteractionsAllowed = 'none';

        end

        % Button pushed function: TwovanishpointsButton
        function TwovanishpointsButtonPushed(app, event)
            app.filebild = GUI_Fluchtpunkt_2(app.filebild, app.ROI_p, app.ROI_P);
            [app.im_width, app.im_length, ~] = size(app.filebild);
            imshow(app.filebild, 'Parent', app.UIAxes);
        end

        % Button pushed function: ROIforforegroundButton
        function ROIforforegroundButtonPushed(app, event)
            H = findobj(app.UIAxes, 'Color', [0 1 0]);
            if ~isempty(H)
                delete(H);
            end

            hold(app.UIAxes, "on")
            app.roi_fg = drawrectangle(app.UIAxes, 'Label', 'Foreground', 'Color', [0 1 0]);
            hold(app.UIAxes, "off")
        end

        % Button pushed function: ForegroundButton
        function ForegroundButtonPushed(app, event)
            app.rxm = [app.roi_fg.Position(1) app.roi_fg.Position(2)];
            app.rym = [app.roi_fg.Position(3) app.roi_fg.Position(4)];
            [app.pm, app.hm, app.wm] = get_image_coordinates_mask(app.rxm, app.rym);
            mask = createMask(app.roi_fg);
            app.maskRGB = create_mask(app.filebild, app.roi_fg);
%             app.foreground=imcrop(app.maskRGB, [app.rxm(1) app.rym(1) app.rxm(2) app.rym(2)]);
            app.filebild = inpaintExemplar(app.filebild, mask);
            [app.im_width, app.im_length, ~] = size(app.filebild);
            imshow(app.filebild, 'Parent', app.UIAxes);

            app.vorwand = true;
        end

        % Button pushed function: ZipimageButton
        function ZipimageButtonPushed(app, event)
            app.filebild = zip_pooling(app.filebild);
            [app.im_width, app.im_length, ~] = size(app.filebild);
            imshow(app.filebild, 'Parent', app.UIAxes);
        end

        % Button pushed function: BackgroundButton
        function BackgroundButtonPushed(app, event)
            H = findobj(app.UIAxes, 'Color', [1 0 0]);
            if ~isempty(H)
                delete(H);
            end

            hold(app.UIAxes, "on")
            app.roi_rw = drawrectangle(app.UIAxes, 'Label', 'Background', 'Color', [1 0 0]);
            hold(app.UIAxes, "off")
        end

        % Button pushed function: VanishpointButton
        function VanishpointButtonPushed(app, event)
            H = findobj(app.UIAxes, 'Type', 'images.roi.Point');
            if ~isempty(H)
                delete(H);
            end
            
            hold(app.UIAxes, "on")
            app.roi_fp = drawpoint(app.UIAxes, 'Label', 'Vanish point', 'Color', 'y');
            hold(app.UIAxes, "off")
        end

        % Button pushed function: RadiationButton
        function RadiationButtonPushed(app, event)
            H = findobj(app.UIAxes, 'Type', 'Line');
            if ~isempty(H)
                delete(H);
            end

            [app.p, app.vx_i, app.vy_i, app.h, app.w] = GUI_get_image_coordinates(app.roi_rw, app.roi_fp, app.im_width, app.im_length);
            
            hold(app.UIAxes, "on")
            if app.p(5, 1) > 1
                plot(app.UIAxes, [app.vx_i app.p(5, 1)], [app.vy_i app.p(5, 2)], 'y', 'LineWidth', 1);
            else
                plot(app.UIAxes, [app.vx_i app.p(12, 1)], [app.vy_i app.p(12, 2)], 'y', 'LineWidth', 1);
            end

            if app.p(6, 1) < app.im_length
                plot(app.UIAxes, [app.vx_i app.p(6, 1)], [app.vy_i app.p(6, 2)], 'y', 'LineWidth', 1);
            else
                plot(app.UIAxes, [app.vx_i app.p(7, 1)], [app.vy_i app.p(7, 2)], 'y', 'LineWidth', 1);
            end
            
            if app.p(9, 1) < app.im_length
                plot(app.UIAxes, [app.vx_i app.p(9, 1)], [app.vy_i app.p(9, 2)], 'y', 'LineWidth', 1);
            else
                plot(app.UIAxes, [app.vx_i app.p(8, 1)], [app.vy_i app.p(8, 2)], 'y', 'LineWidth', 1);
            end

            if app.p(10, 1) > 1
                plot(app.UIAxes, [app.vx_i app.p(10, 1)], [app.vy_i app.p(10, 2)], 'y', 'LineWidth', 1);
            else
                plot(app.UIAxes, [app.vx_i app.p(11, 1)], [app.vy_i app.p(11, 2)], 'y', 'LineWidth', 1);
            end
            hold(app.UIAxes, "off")
        end

        % Button pushed function: DPlotButton
        function DPlotButtonPushed(app, event)
            app.depth_to_rear = app.DepthEditField.Value; % the definition of a variable for calculating the lenth of each face

            [H_left, H_down, H_up, H_right, H_middle, P] = get_world_coordinates(app.p, app.h, app.w);

            [X, Y] = meshgrid(1:app.im_length, 1:app.im_width);
            X = X(:);
            Y = Y(:);

            % down
            [I_new_down8, PW_down, I1_down, I2_down, I3_down] = transfer_into_rectangle_down(app.filebild, app.p, X, Y, H_down);
            I_new_down8 = interpolation(I_new_down8);
            
            % up
            [I_new_up8, PW_up, I1_up, I2_up, I3_up] = transfer_into_rectangle_up(app.filebild, app.p, X, Y, H_up);
            I_new_up8  = interpolation(I_new_up8);

            % left
            [I_new_left8, PW_left, I1_left, I2_left, I3_left] = transfer_into_rectangle_left(app.filebild, app.p, X, Y, H_left);
            I_new_left8 = interpolation(I_new_left8);
            
            % right
            [I_new_right8, PW_right, I1_right, I2_right, I3_right] = transfer_into_rectangle_right(app.filebild, app.p, X, Y, H_right);
            I_new_right8 = interpolation(I_new_right8);
            
            % middle
            [I_new_middle8, PW_middle, I1_middle, I2_middle, I3_middle] = transfer_into_rectangle_middle(app.filebild, app.p, H_middle);
            I_new_middle8 = interpolation(I_new_middle8);
            
            if app.vorwand
                [I_new_middle8_m, PW_middle_m, I1_middle_m, I2_middle_m, I3_middle_m] = transfer_into_rectangle_middle(app.maskRGB, app.pm, H_middle);
                index_m = min(find(I_new_middle8_m(:,1)~=0, 1));
                I_new_middle8_m = interpolation( I_new_middle8_m(index_m:end,:,:) );
                ZM = app.rxm(1)/size(app.filebild,1)*abs((P(1,1)-P(5,3)))*0.5-100; % geometrische Beziehung
                PW1 = [PW_down(1,:),PW_up(1,:),zeros(size(PW_left(1,:))),app.w*ones(size(PW_right(1,:))),PW_middle(1,:),PW_middle_m(1,:)];
                PW2 = [zeros(size(PW_down(1,:))),app.h*ones(size(PW_up(1,:))),PW_left(1,:),PW_right(1,:),PW_middle(2,:),PW_middle_m(2,:)];
                PW3 = [PW_down(2,:),PW_up(2,:),PW_left(2,:),PW_right(2,:),zeros(size(PW_middle(1,:))),ZM*ones(size(PW_middle_m(1,:)))];
                PW4 = [I1_down',I1_up',I1_left',I1_right',I1_middle',I1_middle_m'];
                PW5 = [I2_down',I2_up',I2_left',I2_right',I2_middle',I2_middle_m'];
                PW6 = [I3_down',I3_up',I3_left',I3_right',I3_middle',I3_middle_m'];
                app.PW = [PW1;PW2;PW3;PW4;PW5;PW6]; %3D model (the world coordinates based on P1)
                PW_M = [PW_middle_m;ZM*ones(size(PW_middle_m(1,:)))];
            else
                PW1 = [PW_down(1,:), PW_up(1,:), zeros(size(PW_left(1,:))), app.w*ones(size(PW_right(1,:))), PW_middle(1,:)];
                PW2 = [zeros(size(PW_down(1,:))), app.h*ones(size(PW_up(1,:))), PW_left(1,:), PW_right(1,:), PW_middle(2,:)];
                PW3 = [PW_down(2,:), PW_up(2,:), PW_left(2,:), PW_right(2,:), zeros(size(PW_middle(1,:)))];
                PW4 = [I1_down', I1_up', I1_left', I1_right', I1_middle'];
                PW5 = [I2_down', I2_up', I2_left', I2_right', I2_middle'];
                PW6 = [I3_down', I3_up', I3_left', I3_right', I3_middle'];
                app.PW = [PW1; PW2; PW3; PW4; PW5; PW6]; % 3D model (the world coordinates based on P1);
            end

            % Reset of camera coordinates
            app.PW_cam(1, :) = double(app.PW(1,:)) - abs(app.vx_i-app.p(1,1));
            app.PW_cam(2, :) = double(app.PW(2,:)) - abs(app.vy_i-app.p(1,2));
            app.PW_cam(3, :) = double(app.PW(3,:)) - app.depth_to_rear;
            if app.vorwand
                PW_M(2, :) = double(PW_middle_m(2,:) + abs(app.p(2,2) - app.pm(2,2)));
                PW_M_cam(1, :) = double(PW_M(1,:)) - abs(app.vx_i-app.p(1,1));
                PW_M_cam(2, :) = double(PW_M(2,:)) - abs(app.vy_i-app.p(1,2));
                PW_M_cam(3, :) = ZM - app.depth_to_rear;
            end
            P(:, 1) = P(:, 1) - abs(app.vx_i - app.p(1, 1));
            P(:, 2) = P(:, 2) - abs(app.vy_i - app.p(1, 2));
            P(:, 3) = P(:, 3) - app.depth_to_rear;
%             i_down = size(I1_down, 1);

            R_const = [0 1 0; 1 0 0; 0 0 -1];
            app.PW_cam = R_const * app.PW_cam; % reset the x/y/z-dierection of axis in camera1

            P = R_const * P';
            if app.vorwand
                PW_M_cam = R_const * PW_M_cam;
            end

            % Preprocessing for 'warp'
            xmin = P(1,1);
            ymin = P(2,1);
            xmax = P(1,3);
            ymax = P(2,2);

            left_z = P(3,12);
            right_z = P(3,7);
            down_z = P(3,6);
            up_z = P(3,9);

            zmin = app.depth_to_rear;

            if app.vorwand
                mask_xmin = min(PW_M_cam(1,:));
                mask_xmax = max(PW_M_cam(1,:));
                mask_ymin = min(PW_M_cam(2,:));
                mask_ymax = max(PW_M_cam(2,:));
                Z = PW_M_cam(3,1);
                % Z = rxm(1)/size(I,1)*(depth_to_rear-down_z)
            end

            up_x = [xmax xmax xmax; xmax xmax xmax];
            up_y = [ymin ymin ymin; ymax ymax ymax];
            up_z = [zmin,(up_z+zmin)/2 up_z;zmin,(up_z+zmin)/2 up_z];
            bottom_x = [xmin xmin xmin; xmin xmin xmin];
            bottom_y = [ymin ymin ymin; ymax ymax ymax];
            bottom_z = [zmin,(down_z+zmin)/2 down_z;zmin,(down_z+zmin)/2 down_z];

            if app.vorwand
                ZM2 = app.depth_to_rear- app.rxm(1)/size(app.filebild,1)*(app.depth_to_rear-down_z);
                middle_maskx = [mask_xmin (mask_xmax+mask_xmin)/2 mask_xmax; mask_xmin (mask_xmin+mask_xmax)/2 mask_xmax];
                middle_masky = [mask_ymin mask_ymin mask_ymin; mask_ymax mask_ymax mask_ymax];
                middle_maskz = [ZM2 ZM2 ZM2;ZM2 ZM2 ZM2];
            end
            
            middle_x = [xmin (xmax+xmin)/2 xmax; xmin (xmin+xmax)/2 xmax];
            middle_y = [ymin ymin ymin; ymax ymax ymax];
            middle_z = [zmin zmin zmin; zmin zmin zmin];
            
            left_x = [xmin xmin xmin; xmax xmax xmax];
            left_y = [ymin ymin ymin; ymin ymin ymin];
            left_z = [zmin (left_z+zmin)/2 left_z; zmin (left_z+zmin)/2 left_z];
            
            right_x = [xmin xmin xmin; xmax xmax xmax];
            right_y = [ymax ymax ymax; ymax ymax ymax];
            right_z = [zmin (right_z+zmin)/2 right_z; zmin (right_z+zmin)/2 right_z];
            
            % 3D 'wrap'
            hold(app.UIAxes_3, "on")
            GUI_warp(app, up_x, up_y, up_z, I_new_up8);
            if app.vorwand
                GUI_warp(app, middle_maskx, middle_masky, middle_maskz, I_new_middle8_m);
            end
            GUI_warp(app, bottom_x, bottom_y, bottom_z, I_new_down8);
            GUI_warp(app, right_x, right_y, right_z, I_new_right8);
            GUI_warp(app, left_x, left_y, left_z, I_new_left8);
            GUI_warp(app, middle_x, middle_y, middle_z, I_new_middle8);

            plot(app.UIAxes_3, [0, 0, 0], 'ro', 'LineWidth', 3); % camera_1 position
            text(app.UIAxes_3, 0, 0, 0, '   camera_1 & original point');
            title(app.UIAxes_3, '3D Reconstruction - Coordinates of Camera_1');
            axis(app.UIAxes_3, 'equal');
            set(app.UIAxes_3, 'YDir', 'normal');
            
            hold(app.UIAxes_3, "off")

            % New Figure
            figure 
            hold on
            warp(up_x, up_y, up_z, I_new_up8);
            if app.vorwand
                warp(middle_maskx, middle_masky, middle_maskz, I_new_middle8_m);
            end
            warp(bottom_x, bottom_y, bottom_z, I_new_down8);
            warp(right_x, right_y, right_z, I_new_right8);
            warp(left_x, left_y, left_z, I_new_left8);
            warp(middle_x, middle_y, middle_z, I_new_middle8);
        
            plot([0,0,0],'ro','LineWidth',3); % camera_1 position
            text(0,0,0,'   camera_1 & original point')
            axis equal; % axis equal;  % make X,Y,Z dimentions be equal % axis vis3d;  % freeze the scale for better rotations % axis off;    % turn off the stupid tick marks
            hold off
            title('3D Reconstruction - Coordinates of Camera_1');
            xlabel('X');
            ylabel('Y');
            zlabel('Z');
            set(gca,'YDir','normal')
            
        end

        % Button pushed function: DProjectionButton
        function DProjectionButtonPushed(app, event)
            P_1_hom = app.PW_cam;
            P_1_hom(4, :) = 1;

            alpha = app.R_xEditField.Value; % rotation um x
            beta = app.R_yEditField.Value; % um y
            theta = app.R_zEditField.Value; % um z
            Rotation = Rotation_matrix(alpha, beta, theta);
            Translation = [app.T_xEditField.Value; app.T_xEditField.Value; app.T_xEditField.Value]; 

            x2_cam = Projection(P_1_hom, app.PW, app.depth_to_rear, Rotation, Translation);
            view = plot_new_image(app.filebild, app.vx_i, app.vy_i, x2_cam);

            app.filebild2 = interpolation(view);
            imshow(rot90(app.filebild2, 1), 'Parent', app.UIAxes_2);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);

            app.R_xEditField.Value = 0;
            app.R_yEditField.Value = 0;
            app.R_zEditField.Value = 0;
            app.T_xEditField.Value = 0;
            app.T_yEditField.Value = 0;
            app.T_zEditField.Value = 0;

            app.vorwand = false; % True/False
            app.filebild = []; % Original geladenes Bild
            app.filename = ''; % Filename (.png) vom originalen geladenen Bild
            app.fullname = '';
            app.ROI_p =[]; % Position of roi_fp2_new
            app.ROI_P = []; % Position of roi_fp2
            app.im_width = []; % Höhe des Bildes
            app.im_length = []; % Länge des Bildes
            app.p = []; % feature points
            app.vx_i = []; % coordinates of vanish point
            app.vy_i = []; % coordinates of vanish point
            app.h = []; % height and width of middle face (rear wand)
            app.w = []; % height and width of middle face (rear wand)
            app.depth_to_rear = []; % the definition of a variable for calculating the lenth of each face
            app.PW = []; % 3D model (the world coordinates based on P1)
            app.PW_cam = []; % reset the x/y/z-dierection of axis in camera1
            app.maskRGB = [];
            app.hm = [];
            app.wm = [];
            app.pm = [];
            app.rxm = [];
            app.rym = [];
            app.filebild2 = []; % Erzeugt Bild
        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);

            app.R_xEditField.Value = 0;
            app.R_yEditField.Value = 0;
            app.R_zEditField.Value = 0;
            app.T_xEditField.Value = 0;
            app.T_yEditField.Value = 0;
            app.T_zEditField.Value = 0;

            app.vorwand = false; % True/False
            app.filebild = []; % Original geladenes Bild
            app.filename = ''; % Filename (.png) vom originalen geladenen Bild
            app.ROI_p =[]; % Position of roi_fp2_new
            app.ROI_P = []; % Position of roi_fp2
            app.im_width = []; % Höhe des Bildes
            app.im_length = []; % Länge des Bildes
            app.p = []; % feature points
            app.vx_i = []; % coordinates of vanish point
            app.vy_i = []; % coordinates of vanish point
            app.h = []; % height and width of middle face (rear wand)
            app.w = []; % height and width of middle face (rear wand)
            app.depth_to_rear = []; % the definition of a variable for calculating the lenth of each face
            app.PW = []; % 3D model (the world coordinates based on P1)
            app.PW_cam = []; % reset the x/y/z-dierection of axis in camera1
            app.maskRGB = [];
            app.hm = [];
            app.wm = [];
            app.pm = [];
            app.rxm = [];
            app.rym = [];
            app.filebild2 = []; % Erzeugt Bild

            app.filebild = imread(app.fullname);
            [app.im_width, app.im_length, ~] = size(app.filebild);
            imshow(app.filebild, 'Parent', app.UIAxes);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            filepath = 'neu_image\';
            fullname_new = [filepath, app.filename];
            imwrite(rot90(app.filebild2, 1), fullname_new);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            app.SelectanimageButton = uibutton(app.UIFigure, 'push');
            app.SelectanimageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageFileButtonPushed, true);
            app.SelectanimageButton.Text = 'Select an image';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'G14:Chongyu Zhang / Yunshan Ye / Xuejiao Li / Shenghan Gao / Zhiping Li'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Tour into Picture (G14)'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PrepocessingTab = uitab(app.TabGroup);
            app.PrepocessingTab.Title = 'Prepocessing';
            app.ROIfortwovanishpointsButton = uibutton(app.PrepocessingTab, 'push');
            app.ROIfortwovanishpointsButton.ButtonPushedFcn = createCallbackFcn(app, @ROIfortwovanishpointsButtonPushed, true);
            app.ROIfortwovanishpointsButton.Text = 'ROI for two vanish points';
            app.ForegroundButton = uibutton(app.PrepocessingTab, 'push');
            app.ForegroundButton.ButtonPushedFcn = createCallbackFcn(app, @ForegroundButtonPushed, true);
            app.ForegroundButton.Text = 'Foreground';
            app.TwovanishpointsButton = uibutton(app.PrepocessingTab, 'push');
            app.TwovanishpointsButton.ButtonPushedFcn = createCallbackFcn(app, @TwovanishpointsButtonPushed, true);
            app.TwovanishpointsButton.Text = 'Two vanish points';
            app.ROIforforegroundButton = uibutton(app.PrepocessingTab, 'push');
            app.ROIforforegroundButton.ButtonPushedFcn = createCallbackFcn(app, @ROIforforegroundButtonPushed, true);
            app.ROIforforegroundButton.Text = 'ROI for foreground';
            app.ZipimageButton = uibutton(app.PrepocessingTab, 'push');
            app.ZipimageButton.ButtonPushedFcn = createCallbackFcn(app, @ZipimageButtonPushed, true);
            app.ZipimageButton.Text = 'Zip image';
            app.OriginalperspectiveTab = uitab(app.TabGroup);
            app.OriginalperspectiveTab.Title = 'Original perspective';
            app.BackgroundButton = uibutton(app.OriginalperspectiveTab, 'push');
            app.BackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @BackgroundButtonPushed, true);
            app.BackgroundButton.Text = 'Background';
            app.VanishpointButton = uibutton(app.OriginalperspectiveTab, 'push');
            app.VanishpointButton.ButtonPushedFcn = createCallbackFcn(app, @VanishpointButtonPushed, true);
            app.VanishpointButton.Text = 'Vanish point';
            app.NewperspectiveTab = uitab(app.TabGroup);
            app.NewperspectiveTab.Title = 'New perspective';
            app.RadiationButton = uibutton(app.NewperspectiveTab, 'push');
            app.RadiationButton.ButtonPushedFcn = createCallbackFcn(app, @RadiationButtonPushed, true);
            app.RadiationButton.Text = 'Radiation';
            app.DPlotButton = uibutton(app.NewperspectiveTab, 'push');
            app.DPlotButton.ButtonPushedFcn = createCallbackFcn(app, @DPlotButtonPushed, true);
            app.DPlotButton.Text = '3D Plot';
            app.DProjectionButton = uibutton(app.NewperspectiveTab, 'push');
            app.DProjectionButton.ButtonPushedFcn = createCallbackFcn(app, @DProjectionButtonPushed, true);
            app.DProjectionButton.Text = '2D Projection';
            app.R_xEditFieldLabel = uilabel(app.NewperspectiveTab);
            app.R_xEditFieldLabel.Text = 'R_x';
            app.R_xEditField = uieditfield(app.NewperspectiveTab, 'numeric');
            app.T_xEditFieldLabel = uilabel(app.NewperspectiveTab);
            app.T_xEditFieldLabel.Text = 'T_x';
            app.T_xEditField = uieditfield(app.NewperspectiveTab, 'numeric');
            app.R_yEditFieldLabel = uilabel(app.NewperspectiveTab);
            app.R_yEditFieldLabel.Text = 'R_y';
            app.R_yEditField = uieditfield(app.NewperspectiveTab, 'numeric');
            app.R_zEditFieldLabel = uilabel(app.NewperspectiveTab);
            app.R_zEditFieldLabel.Text = 'R_z';
            app.R_zEditField = uieditfield(app.NewperspectiveTab, 'numeric');
            app.T_zEditFieldLabel = uilabel(app.NewperspectiveTab);
            app.T_zEditFieldLabel.Text = 'T_z';
            app.T_zEditField = uieditfield(app.NewperspectiveTab, 'numeric');
            app.T_yEditFieldLabel = uilabel(app.NewperspectiveTab);
            app.T_yEditFieldLabel.Text = 'T_y';
            app.T_yEditField = uieditfield(app.NewperspectiveTab, 'numeric');
            app.DepthEditFieldLabel = uilabel(app.NewperspectiveTab);
            app.DepthEditFieldLabel.Text = 'Depth';
            app.DepthEditField = uieditfield(app.NewperspectiveTab, 'numeric');
            app.DepthEditField.Value = 400;
            app.OthersTab = uitab(app.TabGroup);
            app.OthersTab.Title = 'Others';
            app.ResetButton = uibutton(app.OthersTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ReloadButton = uibutton(app.OthersTab, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Text = 'Reload';
            app.SaveButton = uibutton(app.OthersTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'pic.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end